classdef HW_2_3_CLA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        evalSS                        matlab.ui.control.Label
        Description                   matlab.ui.control.Label
        ManualImplementation          matlab.ui.control.DropDown
        EigenpaircalculationLabel     matlab.ui.control.Label
        evalARI                       matlab.ui.control.Label
        ConnComps                     matlab.ui.control.Label
        Laplacian                     matlab.ui.control.DropDown
        LaplacianmatrixDropDownLabel  matlab.ui.control.Label
        K                             matlab.ui.control.DropDown
        ValueofkLabel                 matlab.ui.control.Label
        Clustering                    matlab.ui.control.DropDown
        ClusteringLabel               matlab.ui.control.Label
        Dataset                       matlab.ui.control.DropDown
        DatasetDropDownLabel          matlab.ui.control.Label
        ClusterizeButton              matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        clusterGraph                  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function dataset = loadDS(app, data)
            filename = sprintf('%s.mat', data);
            datasetStruct = load(filename); 
            dataset = datasetStruct.X; 
            
        end

        function executeClustering(app, X, clustering, k, spiral,iris,groundTruths,manual)
            %function that directs to the correct clustering function

            description(app, iris, spiral, clustering, k, manual);

            if clustering == "DBSCAN"
                clusters=applyDbscan(app,X,spiral,iris);    
            end
            if clustering == "Spectral Clustering"
                clusters = spectral(app,X,k, iris,manual);
                
            end
            if clustering =="K-means"
                clusters = applyKmeans(app,X,iris,spiral);
            end
            if spiral||iris
                [RI,ARI] = randindex(app,groundTruths, clusters);
                app.evalARI.Text = sprintf("Adjusted Rand Index: %.2f", ARI);   
            end
          
                  
        end
        
        function idx = applyDbscan(app, X,spiral, iris)
            app.UIAxes.Visible = 'off';
            %function that applies the DBSCAN algorithm and plots results.

            if spiral
                idx = dbscan(X, 1.2,3);
            elseif iris
                idx = dbscan(X, 0.8, 3); 
            else
                idx = dbscan(X, 0.8, 3);
            end 
            uniqueClusters = unique(idx);
            hold(app.clusterGraph, 'on');
        
            clusters = cell(length(uniqueClusters), 1);
            if iris
                for i = 1:length(uniqueClusters)
                    clusterIdx = uniqueClusters(i);
                    clusterPoints = X(idx == clusterIdx, :);
                    clusters{i} = clusterPoints;
                    scatter3(app.clusterGraph, clusterPoints(:, 1), clusterPoints(:, 2),clusterPoints(:, 3), ...
                        16, 'filled', 'DisplayName', sprintf('%.d', clusterIdx));
                end

            else
                
                for i = 1:length(uniqueClusters)
                    clusterIdx = uniqueClusters(i);
                    clusterPoints = X(idx == clusterIdx, :);
                    clusters{i} = clusterPoints;
                    scatter(app.clusterGraph, clusterPoints(:, 1), clusterPoints(:, 2), ...
                        16, 'filled', 'DisplayName', sprintf('%d', clusterIdx));
                end
            end
            hold(app.clusterGraph, 'off');
            title(app.clusterGraph, 'DBSCAN Clustering Results');
            xlabel(app.clusterGraph, 'X1');
            ylabel(app.clusterGraph, 'X2');
            zlabel(app.clusterGraph, 'X3');
            legend(app.clusterGraph, 'show');
            if iris
                app.evalSS.Text=sprintf("Average Silhouette Score: %.5f ", calcAvgSilhScore(app,X,idx));
            end
        end

        function idx = applyKmeans(app, X,iris, spiral)
            %function that implements Kmeans, plots results
            app.UIAxes.Visible = 'off';
            rng(42);
            if spiral
                idx = kmeans(X,3 );    
            else
                idx=kmeans(X,2);
            end
            uniqueClusters = unique(idx);
            hold(app.clusterGraph, 'on');
        
            clusters = cell(length(uniqueClusters), 1);
            if iris
                for i = 1:length(uniqueClusters)
                    clusterIdx = uniqueClusters(i);
                    clusterPoints = X(idx == clusterIdx, :);
                    clusters{i} = clusterPoints;
                    scatter3(app.clusterGraph, clusterPoints(:, 1), clusterPoints(:, 2),clusterPoints(:, 3), ...
                        16, 'filled', 'DisplayName', sprintf('%d', clusterIdx));
                end
            else 
                for i = 1:length(uniqueClusters)
                clusterIdx = uniqueClusters(i);
                clusterPoints = X(idx == clusterIdx, :);
                clusters{i} = clusterPoints;
                scatter(app.clusterGraph, clusterPoints(:, 1), clusterPoints(:, 2), ...
                    16, 'filled', 'DisplayName', sprintf('Cluster %d', clusterIdx));
                end
            end
            hold(app.clusterGraph, 'off');
            title(app.clusterGraph, 'K-Means Clustering Results');
            xlabel(app.clusterGraph, 'X1');
            ylabel(app.clusterGraph, 'X2');
            legend(app.clusterGraph, 'show');
            if iris
                zlabel(app.clusterGraph, 'X3');
            end
            if iris
                app.evalSS.Text=sprintf("Average Silhouette Score: %.5f ", calcAvgSilhScore(app,X,idx));     
            end

        end

        function idx = spectral(app,X, K, iris,manual)
            %function that implements all cases of spectral clustering
            app.UIAxes.Visible = 'on';
            [L,D,G]=Laplacian_mat(app,X,K);
            [~,sizes]=conncomp(G);
            app.ConnComps.Text=sprintf("Connected components of the similarity graph: %d",length(sizes));
            %choice of L/Lsym
            if app.Laplacian.Value=="L"
                L_used=L;
                title(app.UIAxes, 'Eigenvalues of L (Spectral clustering)');
            else
                D_inv_sqrt = diag(1 ./ sqrt(diag(D)));
                Lsym = D_inv_sqrt*L*D_inv_sqrt;
                Lsym=min(Lsym,Lsym');
                L_used=Lsym;
                title(app.UIAxes, 'Eigenvalues of Lsym (Spectral clustering)');
            end
            n_computed_eigs = 10;
            %MATLAB default/manual implementation
            if manual
                [eigenvalues, eigvec]=manual_impl(app,L_used,n_computed_eigs);
                
            else
                [eigvec,DiagEigs] = eigs(L_used,n_computed_eigs,'smallestreal');
                DiagEigs(DiagEigs < 1e-7) = 0;
                eigenvalues=diag(DiagEigs);
            end
            xticks(app.UIAxes,linspace(0,10,11));
            plot(app.UIAxes,eigenvalues,'o-');
            for m=1:9
                if eigenvalues(m)==0
                    if eigenvalues(m+1)>=0.085
                        break
                    end
                elseif eigenvalues(m+1)-eigenvalues(m)>=0.5*eigenvalues(m)
                    break
                end
            end
            U=eigvec(:,1:m);
            rng(42);
            [idx,c]=kmeans(U,m);
            clusters = cell(m, 1);
            for i = 1:m
                if iris
                    clusters{i} = X(idx == i,1:3);
                else
                    clusters{i} = X(idx == i, 1:2);
                end
            end
            hold(app.clusterGraph, 'on');
            colors = lines(m);
            if iris

                for i = 1:m
                    scatter3(app.clusterGraph, clusters{i}(:, 1), clusters{i}(:, 2),clusters{i}(:, 3), 16, colors(i, :), 'filled', 'DisplayName', sprintf('Cluster %d', i));
                end
            else
                for i = 1:m
                    scatter(app.clusterGraph, clusters{i}(:, 1), clusters{i}(:, 2), 16, colors(i, :), 'filled', 'DisplayName', sprintf('Cluster %d', i));
                end
            end
            legend(app.clusterGraph, 'show');
            xlabel(app.clusterGraph, 'X1');
            ylabel(app.clusterGraph, 'X2');
            zlabel(app.clusterGraph, 'X3');
            title(app.clusterGraph, 'Clusters of Points in X');
            hold(app.clusterGraph, 'off');
            if iris
                app.evalSS.Text=sprintf("Average Silhouette Score: %.5f ", calcAvgSilhScore(app,X,idx));
            end

        end
        
        function [L,D,G]=Laplacian_mat(app,X,K)
            sigma=1;
            Di=squareform(pdist(X));
            Di=sparse(Di);
            S = exp(-(Di.^2 / (2 * sigma^2)));
            S(eye(size(S)) == 1) = 0;
            S(S < 10e-7) = 0;

            W=zeros(length(X),length(X));
            W = sparse(size(S, 1), size(S, 2));
            nPoints = size(X, 1);
            for i = 1:nPoints
                [~, indices] = sort(S(i, :), 'descend'); 
                neighbors = indices(1:str2num(K)); 
                W(i, neighbors) = S(i, neighbors);
            end            
            W = max(W, W');
            G = graph(W);
            D=diag(sum(W,2));
            L=D-W;
        end
        
        function [eigenvalues,eigvec]= manual_impl(app,L_used,n_computed_eigs)
            %in case of manual computation of the eigenpairs:
            %power methodf, inverse power method, deflation.
            tol=1e-4;
            max_iter=10000;
            n = size(L_used, 1);
            IV=[ones(n-1,1);0];
            biggestlambda=powermethod(app,L_used,IV, max_iter,tol);
            eigenvalues = zeros(n_computed_eigs, 1);
            eigvec = zeros(n,n_computed_eigs);
            start=1;
            if app.Laplacian.Value=='L'
                vec=ones(n,1)/norm(ones(n,1));
                eigenvalues(1) = 0;
                eigvec(:, 1) = vec;
                L_used=deflate(app,L_used,vec,biggestlambda);
                start=2;
            end
            for i = start:n_computed_eigs
                [lambda, vec] = invpowermethod(app,L_used,IV, max_iter,tol);
                eigenvalues(i) = lambda;
                eigvec(:, i) = vec;
                L_used = deflate(app,L_used, vec, biggestlambda); 
            end
            [eigenvalues, indices] = sort(eigenvalues);
            eigvec = eigvec(:, indices);
        end
        
        function [eigenval,eigenvec] = invpowermethod(app,A,v0, maxIter,relTol)
            vk=v0/norm(v0);
            e=10000;
            e0=10000000;
            k=0;
            while k<=maxIter && abs(e0-e)>=relTol*abs(e)
                v0=vk;
                e0=e;
                vk=A\v0;
                e=v0'*vk;
                vk=vk/norm(vk);
                k=k+1;
            end
            eigenval=1/e;
            if eigenval<0.000001
                eigenval=0;
            end
            eigenvec=vk;
        end
        
        function eigenval = powermethod(app,A,v0, maxIter,relTol)
            vk=v0/norm(v0);
            e=10000;
            e0=10000000;
            k=0;
            while k<=maxIter && abs(e0-e)>=relTol*abs(e)
                v0=vk;
                e0=e;
                vk=A*v0;
                e=v0'*vk;
                vk=vk/norm(vk);
                k=k+1;
            end
            eigenval=e;
        end
        
        function defL = deflate(app,L,vec,biggestlambda)
            %deflation technique
            vec=vec/norm(vec);
            defL = L + 1.5* biggestlambda * (vec * vec');
        end
        
        function meanSS = calcAvgSilhScore(app, X, idx)
            meanSS = mean(silhouette(X, idx)); 
            meanSS = round(meanSS, 5);
        end
        
        %rand index, adjusted rand index function cited in the References section of the report
        function [RI, ARI] = randindex(app,labels1, labels2)

            % The function calculates the Rand index (RI) and Adjusted Rand index (ARI)
            % between two label assignments: labels1 and labels2.
        
            N = numel(labels1);  % Get the number of elements in the label vector
            
            % Initialize the four quantities: TP (true positive), FN (false negative), FP (false positive), TN (true negative)
            TP = 0; FN = 0; FP = 0; TN = 0;
            
            % Calculate TP, FN, FP and TN
            for i = 1:N-1
                for j = i+1:N
                    if (labels1(i) == labels1(j)) && (labels2(i) == labels2(j))  % TP: Both labels1 and labels2 have the same class for samples i and j
                        TP = TP + 1;
                    elseif (labels1(i) == labels1(j)) && (labels2(i) ~= labels2(j))  % FN: labels1 have the same class for samples i and j, but labels2 not
                        FN = FN + 1;
                    elseif (labels1(i) ~= labels1(j)) && (labels2(i) == labels2(j))  % FP: labels2 have the same class for samples i and j, but labels1 not
                        FP = FP + 1;
                    else   % TN: Both labels1 and labels2 have different classes for samples i and j
                        TN = TN + 1;
                    end
                end
            end
            
            % Calculate Rand Index (RI)
            RI = (TP + TN) / (TP + FP + FN + TN);
            
            % Calculate Adjusted Rand Index (ARI)
            try
                C = confusionmat(app,labels1, labels2);  % Use built-in function to generate confusion matrix
            catch
                C = myConfusionMat(app,labels1, labels2);  % If built-in function is not available, use the self-defined function
            end
            
            % Compute necessary quantities for ARI calculation
            sum_C = sum(C(:));
            sum_C2 = sum_C * (sum_C - 1);
            sum_rows = sum(C, 2);
            sum_rows2 = sum(sum_rows .* (sum_rows - 1));
            sum_cols = sum(C, 1);
            sum_cols2 = sum(sum_cols .* (sum_cols - 1));
            sum_Cij2 = sum(sum(C .* (C - 1)));
            
            % Compute ARI
            ARI = 2 * (sum_Cij2 - sum_rows2 * sum_cols2 / sum_C2) / ...
                ((sum_rows2 + sum_cols2) - 2 * sum_rows2 * sum_cols2 / sum_C2);
        end
        
        function C = myConfusionMat(app,g1, g2)
            % This function generates a confusion matrix if the built-in function confusionmat is not available.
            % It takes two input vectors, g1 and g2, which represent two different label assignments.
            
            groups = unique([g1;g2]);  % Get the unique groups in g1 and g2
            
            C = zeros(length(groups));  % Initialize the confusion matrix with zeros
            
            % Calculate the confusion matrix
            for i = 1:length(groups)
                for j = 1:length(groups)
                    C(i,j) = sum(g1 == groups(i) & g2 == groups(j));  % Count the number of samples that belong to group i in g1 and group j in g2
                end
            end
        end  
        
        function clear(app)
            %function that clears the output of previous clustering
            app.Description.Text="";
            app.ConnComps.Text="";
            app.evalARI.Text="";
            app.evalSS.Text="";
            cla(app.clusterGraph);
            cla(app.UIAxes);
            title(app.clusterGraph, 'Clustering Results');
            title(app.UIAxes, 'Eigenvalues of the Laplacian');
            legend(app.clusterGraph, 'hide');
        end
        
        function description(app, iris, spiral, clustering, k, manual)
            %function that writes information about the clusterings in the
            %GUI
            if iris
                DS = "Iris";
            elseif spiral
                DS = "Spiral";
            else
                DS = "Circle";
            end
            
            if clustering ~= "Spectral Clustering"
                k = "None";
                L = "None";
                Eigs = "None";
            else
                L = app.Laplacian.Value;
                if manual
                    Eigs = "manual";
                else
                    Eigs = "MATLAB default";
                end
            end
            
            app.Description.Text = sprintf('DS= %s,  Clustering: %s,  K= %s, \n Laplacian= %s, Eigenpair calculation= %s', DS, clustering, k, L, Eigs);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClusterizeButton
        function ClusterizeButtonPushed(app, event)
            %main function
            app.clear;
            datasetName = app.Dataset.Value;
            clustering = app.Clustering.Value;
            k = app.K.Value;
            groundTruths=NaN;
            spiral = false;
            iris=false;
            manual=false;
            dataset = app.loadDS(datasetName);
            if datasetName=="Spiral"
                spiral = true;
                groundTruths=dataset(:,3);
                dataset=dataset(:,1:2);
            
            else    
                app.ConnComps.Text="";
                app.evalARI.Text="";
            end
            if datasetName=="Iris"
                iris = true;
                groundTruths=dataset(:,4);
                dataset=dataset(:,1:3);
                view(app.clusterGraph, 3);
            else
                view(app.clusterGraph, 2);
            end
            if app.ManualImplementation.Value == "Manual implementation"
                manual=true;
            end
            %apply the selected clustering
            app.executeClustering(dataset, clustering,k, spiral, iris,groundTruths,manual);

        end

        % Callback function
        function ClearButtonPushed(app, event)


        end

        % Value changed function: Clustering
        function ClusteringValueChanged(app, event)
            clustering = app.Clustering.Value;
            %enabling/disabling the dropdowns if the clustering dropdown
            %is set to Spectral Clustering.
            if clustering == "Spectral Clustering"
                app.K.Enable = 'on';
                app.Laplacian.Enable = 'on';
                app.ManualImplementation.Enable = 'on';
            else
                app.K.Enable = 'off';
                app.Laplacian.Enable = 'off';
                app.ManualImplementation.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.clusterGraph = uiaxes(app.UIFigure);
            title(app.clusterGraph, 'Clustering results')
            xlabel(app.clusterGraph, 'X')
            ylabel(app.clusterGraph, 'Y')
            zlabel(app.clusterGraph, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Eigenvalues (spectral clustering)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ClusterizeButton = uibutton(app.UIFigure, 'push');
            app.ClusterizeButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterizeButtonPushed, true);
            app.ClusterizeButton.Text = 'Clusterize';
            app.DatasetDropDownLabel = uilabel(app.UIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset:';
            app.Dataset = uidropdown(app.UIFigure);
            app.Dataset.Items = {'Circle', 'Spiral', 'Iris'};
            app.Dataset.Value = 'Circle';
            app.ClusteringLabel = uilabel(app.UIFigure);
            app.ClusteringLabel.Text = 'Clustering:';
            app.Clustering = uidropdown(app.UIFigure);
            app.Clustering.Items = {'Spectral Clustering', 'DBSCAN', 'K-means'};
            app.Clustering.ValueChangedFcn = createCallbackFcn(app, @ClusteringValueChanged, true);
            app.Clustering.Value = 'Spectral Clustering';
            app.ValueofkLabel = uilabel(app.UIFigure);
            app.ValueofkLabel.Text = 'Value of k:';
            app.K = uidropdown(app.UIFigure);
            app.K.Items = {'10', '20', '40'};
            app.K.Value = '10';
            app.LaplacianmatrixDropDownLabel = uilabel(app.UIFigure);
            app.LaplacianmatrixDropDownLabel.Text = 'Laplacian matrix:';
            app.Laplacian = uidropdown(app.UIFigure);
            app.Laplacian.Items = {'L', 'Lsym'};
            app.Laplacian.Value = 'L';
            app.ConnComps = uilabel(app.UIFigure);
            app.ConnComps.Text = '';
            app.evalARI = uilabel(app.UIFigure);
            app.evalARI.Text = '';
            app.EigenpaircalculationLabel = uilabel(app.UIFigure);
            app.EigenpaircalculationLabel.Text = 'Eigenpair calculation:';
            app.ManualImplementation = uidropdown(app.UIFigure);
            app.ManualImplementation.Items = {'MATLAB default', 'Manual implementation'};
            app.ManualImplementation.Value = 'MATLAB default';
            app.Description = uilabel(app.UIFigure);
            app.Description.Text = '';
            app.evalSS = uilabel(app.UIFigure);
            app.evalSS.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW_2_3_CLA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end